classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChooseImageButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        SaveImageButton              matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        NonUniformRotationButton     matlab.ui.control.Button
        NonUniformScalingButton      matlab.ui.control.Button
        RevertButton                 matlab.ui.control.Button
        TransformationsLabel         matlab.ui.control.Label
        SelectregionrectangleButton  matlab.ui.control.Button
        MoreButton                   matlab.ui.control.Button
        ZoomBackOutofSelectionkeepeditsButton  matlab.ui.control.Button
        ShowHistogramsButton         matlab.ui.control.Button
        RSliderLabel                 matlab.ui.control.Label
        RSlider                      matlab.ui.control.Slider
        GSliderLabel                 matlab.ui.control.Label
        GSlider                      matlab.ui.control.Slider
        BSliderLabel                 matlab.ui.control.Label
        BSlider                      matlab.ui.control.Slider
        FunctionsLabel               matlab.ui.control.Label
        UniformScalingButton         matlab.ui.control.Button
        UniformRotationButton        matlab.ui.control.Button
    end

    
    properties (Access = public)
        h = NaN; % Description
    end
    
    properties (Access = private)
        imageglob; % Description
        image_zoomed_out = NaN;
        image_original;
        image_orig_color;
        fid = NaN;
        image_name;
    end
    
    methods (Access = private)
        
        
        function results = MakeHistogram(app)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            %make histogram comparing R, G, B of both images displayed and then save it to current directory
            y = figure;
            subplot(2, 3, 1)
            histogram(app.image_original(:, :, 1))
            ylabel('Pixels')
            xlabel('Intensity')
            title('R of original image')
            subplot(2, 3, 2)
            histogram(app.image_original(:, :, 2))
            ylabel('Pixels')
            xlabel('Intensity')
            title('G of original image')
            subplot(2, 3, 3)
            histogram(app.image_original(:, :, 3));
            ylabel('Pixels')
            xlabel('Intensity')
            title('B of original image')
            
            subplot(2, 3, 4)
            histogram(app.imageglob(:, :, 1))
            ylabel('Pixels')
            xlabel('Intensity')
            title('R of edited')
            subplot(2, 3, 5)
            histogram(app.imageglob(:, :, 2))
            ylabel('Pixels')
            xlabel('Intensity')
            title('G of edited')
            subplot(2, 3, 6)
            histogram(app.imageglob(:, :, 3));
            ylabel('Pixels')
            xlabel('Intensity')
            title('B of edited')
            fprintf(app.fid, 'Show Histograms called.\n');
            saveas(y, 'histogram', 'jpg');
            results = NaN;
        end
    end
    

    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            [file, path] = uigetfile('*.*', 'Choose an image');
            %keep window in focus
            figure(app.UIFigure)
            %if the user selected a file
            app.fid = fopen('ImageManipulationOutput.txt', 'w');
            if app.fid == -1
                error('Could not open file: %s\n', 'ImageManipulationOutput.txt')
            end
            if file ~= 0
                fullpath = fullfile(path, file);
                app.image_name = file;
                image = imread(fullpath);
                app.imageglob = image;
                app.image_original = image;
                imshow(image, 'parent', app.UIAxes)
                app.UIAxes.Visible = 'on';
                imshow(image, 'parent', app.UIAxes2)
                app.UIAxes2.Visible = 'on';
                title(app.UIAxes, file)
                fprintf(app.fid, 'Opened %s.\n', fullpath);
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            %https://www.mathworks.com/matlabcentral/answers/439526-how-can-i-check-a-specific-axes-has-an-image-or-not
            if size(getimage(app.UIAxes2)) ~= 0
                [file, user] = imsave(app.UIAxes2);
                if user == 1
                    figure(app.UIFigure)
                    return
                end
                app.image_original = app.imageglob;
                index = strfind(file, '\');
                index = index(end);
                imagesc(app.UIAxes, app.image_original)
                app.image_name = file(index+1:end);
                title(app.UIAxes, file(index+1:end))
                figure(app.UIFigure)
                fprintf(app.fid, 'Saved to %s.\n', file);
            end
        end

        % Button pushed function: SelectregionrectangleButton
        function SelectregionrectangleButtonPushed(app, event)
            %[row, col, ~] = size(getimage(app.UIAxes2));
            if size(getimage(app.UIAxes2)) ~= 0
                %https://www.mathworks.com/matlabcentral/answers/415269-is-there-an-equivalent-to-imrect-for-uiaxes-or-any-other-way-to-get-coordinates-out
                imshow(getimage(app.UIAxes2))
                temp = gca;
                temp.Visible = 'On';
                rect = drawrectangle;
                if ~isvalid(rect)
                    return
                end
                app.h = rect.Position;
                if ~isnan(app.h)
                    close all
                else
                    return
                end
                app.image_zoomed_out = app.imageglob;
                xmin = app.h(2);
                xmax = app.h(4) + app.h(2);
                ymin = app.h(1);
                ymax = app.h(3) + app.h(1);
                app.imageglob = app.image_zoomed_out(round(xmin:xmax), round(ymin:ymax), :);
                imagesc(app.UIAxes2, app.imageglob)
                app.image_orig_color = app.imageglob;
                fprintf(app.fid, 'Rectangle selected. X = %1.f-%1.f. Y = %1.f-%1.f\n', xmin, xmax, ymin, ymax);
            end
        end

        % Button pushed function: NonUniformRotationButton
        function NonUniformRotationButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            list = {'Outward', 'Inward'};
            [index, tf] = listdlg('ListString', list, 'SelectionMode', 'single');
            if tf == 0
                figure(app.UIFigure)
                return
            end
            [~, ~, n] = size(app.imageglob);
            if n == 1
                image = NonUniformRotate(app.imageglob, index);
                app.imageglob = image;
                imagesc(app.UIAxes2, image)
            elseif n == 3
                image = NonUniformRotateRGB(app.imageglob, index);
                app.imageglob = image;
                imagesc(app.UIAxes2, image)
            else
                errordlg('This image does not work for this operation', 'Operation Error')
            end
            fprintf(app.fid, 'Non uniform rotation called.\n');
            figure(app.UIFigure)
        end

        % Button pushed function: NonUniformScalingButton
        function NonUniformScalingButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            list = {'Outward', 'Inward'};
            [index, tf] = listdlg('ListString', list, 'SelectionMode', 'single');
            if tf == 0
                figure(app.UIFigure)
                return
            end
            [~, ~, n] = size(app.imageglob);
            if n == 1
                [~, ~, image] = NonUniformScale(app.imageglob, index);
                app.imageglob = image;
                imagesc(app.UIAxes2, image)
            elseif n == 3
                image = NonUniformScaleRGB(app.imageglob, index);
                app.imageglob = image;
                imagesc(app.UIAxes2, image)
            else
                errordlg('This image does not work for this operation', 'Operation Error')
            end
            fprintf(app.fid, 'Non uniform scaling called.\n');
            figure(app.UIFigure)
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            app.imageglob = app.image_original;
            app.image_orig_color = app.image_original;
            imagesc(app.UIAxes2, app.imageglob)
            figure(app.UIFigure)
            fprintf(app.fid, 'Revert called.\n');
        end

        % Button pushed function: MoreButton
        function MoreButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            list = {'Contrast Enhance', 'Smoothing', 'Trace Edge', 'Portrait (unfinished)'};
            [index, tf] = listdlg('ListString', list, 'SelectionMode', 'single');
            
            if tf == 0
                figure(app.UIFigure)
                return
            end
            
            [~, ~, n] = size(app.imageglob);
            
            if index == 1
                %contrast enhance
                if n == 1
                    app.imageglob = gray2rgb(app.imageglob);
                end
                app.imageglob = ContrastEnhance(app.imageglob);
                imagesc(app.UIAxes2, app.imageglob);
                figure(app.UIFigure)
                fprintf(app.fid, 'Contrast enhanced called.\n');
            elseif index == 2
                %smoothing
                list = {'Gaussian (recommended)', 'Box'};
                [type, tf] = listdlg('ListString', list, 'SelectionMode', 'single');
                
                if tf == 0
                    figure(app.UIFigure)
                    return
                end
                %get sigma and filter size
                x = inputdlg({'Sigma (positive integer)', 'Filter size parameter 1', 'Filter size parameter 2'}, 'Parameters',...
                    [1 1 1], {'5', '10', '10'});
                
                if isempty(x)
                    figure(app.UIFigure)
                    return
                end
                if type == 1
                    filter = 'gaussian';
                elseif type == 2
                    filter = 'box';
                end
                if n == 1
                    app.imageglob = Smoothing(app.imageglob, filter, [x{2} x{3}], x{1});
                    imagesc(app.UIAxes2, app.imageglob)
                    figure(app.UIFigure)
                elseif n == 3
                    app.imageglob = SmoothingRGB(app.imageglob, filter, [round(str2double(x{2})) round(str2double(x{3}))], str2double(x{1}));
                    imagesc(app.UIAxes2, app.imageglob)
                    figure(app.UIFigure)
                end
                fprintf(app.fid, 'Smoothing called with %s filter, [%s %s] filter size, and sigma = %s.\n', filter, x{2}, x{3}, x{1});
            elseif index == 3
                %trace edge
                list = {'Outline (Default)', 'Outline (Custom)'};
                [index, tf] = listdlg('ListString', list, 'SelectionMode', 'single');
                if tf == 0
                    figure(app.UIFigure)
                    return
                end
                if index == 1
                    app.imageglob = DisplayOutlineDefault(app.imageglob);
                    fprintf(app.fid, 'Display Outline Default called.\n');
                elseif index == 2
                    x = inputdlg({'Red (0-255):', 'Green (0-255):', 'Blue (0-255):'}, 'Colors', [1 1 1]);
                    if isempty(x)
                        figure(app.UIFigure)
                        return
                    end
                    app.imageglob = DisplayOutline(app.imageglob, round(str2double(x{1})), round(str2double(x{2})), round(str2double(x{3})));
                    fprintf(app.fid, 'Display Outline called with R = %s, G = %s, B = %s.\n', x{1}, x{2}, x{3});
                end
                imagesc(app.UIAxes2, app.imageglob)
                figure(app.UIFigure)
            elseif index == 4
                %make portrait function; incomplete
                x = inputdlg({'Sigma (positive integer)', 'Filter size parameter 1', 'Filter size parameter 2'}, 'Parameters',...
                    [1 1 1], {'5', '10', '10'});
                if isempty(x)
                    figure(app.UIFigure)
                    return
                end
                app.imageglob = MakePortrait(app.imageglob, [round(str2double(x{2})) round(str2double(x{3}))], round(str2double(x{1})));
                imagesc(app.UIAxes2, app.imageglob)
                figure(app.UIFigure)
                fprintf(app.fid, 'Make Portrait called with filter size [%s %s] and sigma = %s.\n', x{2}, x{3}, x{1});
            end
        end

        % Button pushed function: 
        % ZoomBackOutofSelectionkeepeditsButton
        function ZoomBackOutofSelectionkeepeditsButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            [row, col, ~] = size(app.image_zoomed_out);
            if isnan(app.image_zoomed_out)
                return
            end
            row = round(0.005*row);
            col = round(0.005*col);
            %make displayed image keep the edits while zooming out
            app.image_zoomed_out(round(app.h(2)+col:app.h(2)+app.h(4)-col), round(app.h(1)+row:app.h(3)+app.h(1)-row), :) = app.imageglob(round(1+col:end-col), round(1+row:end-row), :);
            app.imageglob = app.image_zoomed_out;
            app.h = NaN;
            app.image_zoomed_out = NaN;
            app.image_orig_color = app.imageglob;
            imagesc(app.UIAxes2, app.imageglob)
            figure(app.UIFigure)
            fprintf(app.fid, 'Zoom Back Out of Selection called.\n');
        end

        % Button pushed function: ShowHistogramsButton
        function ShowHistogramsButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            subplot(2, 3, 1)
            histogram(app.image_original(:, :, 1))
            ylabel('Pixels')
            xlabel('Intensity')
            title('R of original image')
            subplot(2, 3, 2)
            histogram(app.image_original(:, :, 2))
            ylabel('Pixels')
            xlabel('Intensity')
            title('G of original image')
            subplot(2, 3, 3)
            histogram(app.image_original(:, :, 3));
            ylabel('Pixels')
            xlabel('Intensity')
            title('B of original image')
            
            subplot(2, 3, 4)
            histogram(app.imageglob(:, :, 1))
            ylabel('Pixels')
            xlabel('Intensity')
            title('R of edited')
            subplot(2, 3, 5)
            histogram(app.imageglob(:, :, 2))
            ylabel('Pixels')
            xlabel('Intensity')
            title('G of edited')
            subplot(2, 3, 6)
            histogram(app.imageglob(:, :, 3));
            ylabel('Pixels')
            xlabel('Intensity')
            title('B of edited')
            fprintf(app.fid, 'Show Histograms called.\n');
        end

        % Callback function
        function closeallButtonPushed(app, event)
            close all
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            image = app.image_orig_color;
            [row, ~, ~] = size(image);
            if row == 0
                image = app.imageglob;
            end
            value = app.BSlider.Value;
            fprintf(app.fid, 'Blue slider changed to %1.f%%\n', value);
            value = value/100;
            B = image(:, :, 3);
            B = uint8(B * value);
            B(B>255) = 255;
            app.imageglob(:, :, 3) = B(:, :);
            imagesc(app.UIAxes2, app.imageglob)
            figure(app.UIFigure)
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            image = app.image_orig_color;
            [row, ~, ~] = size(image);
            if row == 0
                image = app.imageglob;
            end
            value = app.GSlider.Value;
            fprintf(app.fid, 'Green slider changed to %1.f%%\n', value);
            value = value/100;
            G = image(:, :, 2);
            G = uint8(G * value);
            G(G>255) = 255;
            app.imageglob(:, :, 2) = G(:, :);
            imagesc(app.UIAxes2, app.imageglob)
            figure(app.UIFigure)
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            image = app.image_orig_color;
            [row, ~, ~] = size(image);
            if row == 0
                image = app.imageglob;
            end
            value = app.RSlider.Value;
            fprintf(app.fid, 'Red slider changed to %1.f%%\n', value);
            value = value/100;
            R = image(:, :, 1);
            R = uint8(R * value);
            R(R>255) = 255;
            app.imageglob(:, :, 1) = R(:, :);
            imagesc(app.UIAxes2, app.imageglob)
            figure(app.UIFigure)
        end

        % Button pushed function: UniformRotationButton
        function UniformRotationButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            [~, ~, n] = size(app.imageglob);
            if n == 1
                x = inputdlg({'Theta (Degrees): '}, 'Uniform Rotation', 1);
                if isempty(x)
                    figure(app.UIFigure)
                    return
                end
                [~, ~, app.imageglob] = UniformRotate(app.imageglob, round(str2double(x{1})));
                imagesc(app.UIAxes2, app.imageglob)
                figure(app.UIFigure)
                fprintf(app.fid, 'Uniform rotation called with theta = %s.\n', x{1});
            elseif n == 3
                x = inputdlg({'Theta (Degrees): '}, 'Uniform Rotation', 1);
                if isempty(x)
                    figure(app.UIFigure)
                    return
                end
                [~, ~, app.imageglob] = UniformRotateRGB(app.imageglob, round(str2double(x{1})));
                imagesc(app.UIAxes2, app.imageglob)
                figure(app.UIFigure)
                fprintf(app.fid, 'Uniform rotation called with theta = %s.\n', x{1});
            end
        end

        % Button pushed function: UniformScalingButton
        function UniformScalingButtonPushed(app, event)
            if size(getimage(app.UIAxes2)) == 0
                return
            end
            [~, ~, n] = size(app.imageglob);
            if n == 1
                x = inputdlg({'Scaling factor: '}, 'Uniform Scaling', 1);
                if isempty(x)
                    figure(app.UIFigure)
                    return
                end
                [~, ~, app.imageglob] = UniformScale(app.imageglob, round(str2double(x{1})), false);
                imagesc(app.UIAxes2, app.imageglob)
                figure(app.UIFigure)
                fprintf(app.fid, 'Uniform scaling called with factor = %s.\n', x{1});
            elseif n == 3
                x = inputdlg({'Scaling factor: '}, 'Uniform Scaling', 1);
                if isempty(x)
                    figure(app.UIFigure)
                    return
                end
                [~, ~, app.imageglob] = UniformScaleRGB(app.imageglob, round(str2double(x{1})), false);
                imagesc(app.UIAxes2, app.imageglob)
                figure(app.UIFigure)
                fprintf(app.fid, 'Uniform scaling called with factor = %s.\n', x{1});
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MakeHistogram;
            if app.fid ~= -1 || isnan(app.fid)
                fclose(app.fid);
            end
            if ~isnan(app.image_name)
                index = strfind(app.image_name, '.');
                index = index(end);
                name = strcat(app.image_name(1:index-1), '-edited');
                name = strcat(name, app.image_name(index:end));
                imwrite(app.imageglob, name)
            end
            close all
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image...';
            app.UIAxes = uiaxes(app.UIFigure);
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image...';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Edited Version')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.NonUniformRotationButton = uibutton(app.UIFigure, 'push');
            app.NonUniformRotationButton.ButtonPushedFcn = createCallbackFcn(app, @NonUniformRotationButtonPushed, true);
            app.NonUniformRotationButton.Text = 'Non-Uniform Rotation';
            app.NonUniformScalingButton = uibutton(app.UIFigure, 'push');
            app.NonUniformScalingButton.ButtonPushedFcn = createCallbackFcn(app, @NonUniformScalingButtonPushed, true);
            app.NonUniformScalingButton.Text = 'Non-Uniform Scaling';
            app.RevertButton = uibutton(app.UIFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.TransformationsLabel = uilabel(app.UIFigure);
            app.TransformationsLabel.Text = 'Transformations';
            app.SelectregionrectangleButton = uibutton(app.UIFigure, 'push');
            app.SelectregionrectangleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectregionrectangleButtonPushed, true);
            app.SelectregionrectangleButton.Text = 'Select region (rectangle)...';
            app.MoreButton = uibutton(app.UIFigure, 'push');
            app.MoreButton.ButtonPushedFcn = createCallbackFcn(app, @MoreButtonPushed, true);
            app.MoreButton.Text = 'More...';
            app.ZoomBackOutofSelectionkeepeditsButton = uibutton(app.UIFigure, 'push');
            app.ZoomBackOutofSelectionkeepeditsButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomBackOutofSelectionkeepeditsButtonPushed, true);
            app.ZoomBackOutofSelectionkeepeditsButton.Text = {'Zoom Back Out of Selection '; '(keep edits)...'};
            app.ShowHistogramsButton = uibutton(app.UIFigure, 'push');
            app.ShowHistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHistogramsButtonPushed, true);
            app.ShowHistogramsButton.Text = 'Show Histograms';
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [0 200];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.Value = 100;
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.UIFigure);
            app.GSlider.Limits = [0 200];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.Value = 100;
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [0 200];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.Value = 100;
            app.FunctionsLabel = uilabel(app.UIFigure);
            app.FunctionsLabel.Text = 'Functions';
            app.UniformScalingButton = uibutton(app.UIFigure, 'push');
            app.UniformScalingButton.ButtonPushedFcn = createCallbackFcn(app, @UniformScalingButtonPushed, true);
            app.UniformScalingButton.Text = 'Uniform Scaling';
            app.UniformRotationButton = uibutton(app.UIFigure, 'push');
            app.UniformRotationButton.ButtonPushedFcn = createCallbackFcn(app, @UniformRotationButtonPushed, true);
            app.UniformRotationButton.Text = 'Uniform Rotation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end